classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the model.
            m = model.Model;

            % Create the view.
            component.View( m, "Parent", app.GridLayout );

            % Create the controller.
            component.Controller( m, "Parent",  app.GridLayout  );

            % Create toolbar to reset the model.
            icon = imread( "Reset.jpg" );
            tb = uitoolbar( "Parent", app.UIFigure);

            uipushtool( ...
                "Parent", tb, ...
                "Icon", imresize( icon, [20,20] ), ...
                "Tooltip", "Reset the data.", ...
                "ClickedCallback", @onReset );

            function onReset( ~, ~ )
                %ONRESET Callback function for the toolbar reset button.

                % Reset the model.
                reset( m )
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end